classdef normal_distribution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataHigher                      matlab.ui.control.NumericEditField
        ProbabilitydataXEditField_2Label  matlab.ui.control.Label
        DataLess                        matlab.ui.control.NumericEditField
        ProbabilitydataXEditFieldLabel  matlab.ui.control.Label
        DataEqual                       matlab.ui.control.NumericEditField
        ProbabilitydataXLabel           matlab.ui.control.Label
        DataInput                       matlab.ui.control.NumericEditField
        InputXEditFieldLabel            matlab.ui.control.Label
        Mean                            matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        Sigma                           matlab.ui.control.NumericEditField
        StandarddeviationEditFieldLabel  matlab.ui.control.Label
        plot_of_nd                      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        
        function results = func(app)
            sigmaval = app.Sigma.Value;
            meanval = app.Mean.Value;
            f = 1/(sigmaval * sqrt(2*pi));
            fp= @(x)f* exp((-(x-meanval).^2)/(2*sigmaval.^2));
            Xax= (meanval-3.5*sigmaval):0.1:(meanval+3.5*sigmaval);
            plot(app.plot_of_nd,Xax,fp(Xax));
            hold(app.plot_of_nd,'on');
            area(app.plot_of_nd,Xax(1):app.DataInput.Value,fp(Xax(1):app.DataInput.Value),'FaceColor','m','FaceAlpha',.7);
            hold(app.plot_of_nd,'off');
            app.DataLess.Value=integral(fp,-Inf,app.DataInput.Value);
            app.DataHigher.Value=integral(fp,app.DataInput.Value,inf);
            app.DataEqual.Value=fp(app.DataInput.Value);
            results = fp(app.DataInput.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Mean
        function MeanValueChanged(app, event)
            value = app.Mean.Value;
            func(app)
        end

        % Value changed function: Sigma
        function SigmaValueChanged(app, event)
            value = app.Sigma.Value;
            func(app)
        end

        % Value changed function: DataInput
        function DataInputValueChanged(app, event)
            value = app.DataInput.Value;
            func(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot_of_nd = uiaxes(app.UIFigure);
            title(app.plot_of_nd, 'Normal distribution')
            xlabel(app.plot_of_nd, 'X')
            ylabel(app.plot_of_nd, 'φ(x)')
            zlabel(app.plot_of_nd, 'Z')
            app.StandarddeviationEditFieldLabel = uilabel(app.UIFigure);
            app.StandarddeviationEditFieldLabel.Text = 'Standard deviation';
            app.Sigma = uieditfield(app.UIFigure, 'numeric');
            app.Sigma.ValueChangedFcn = createCallbackFcn(app, @SigmaValueChanged, true);
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean ';
            app.Mean = uieditfield(app.UIFigure, 'numeric');
            app.Mean.ValueChangedFcn = createCallbackFcn(app, @MeanValueChanged, true);
            app.InputXEditFieldLabel = uilabel(app.UIFigure);
            app.InputXEditFieldLabel.Text = 'Input X';
            app.DataInput = uieditfield(app.UIFigure, 'numeric');
            app.DataInput.ValueChangedFcn = createCallbackFcn(app, @DataInputValueChanged, true);
            app.ProbabilitydataXLabel = uilabel(app.UIFigure);
            app.ProbabilitydataXLabel.Text = 'Probability(data=X)';
            app.DataEqual = uieditfield(app.UIFigure, 'numeric');
            app.DataEqual.Editable = 'off';
            app.ProbabilitydataXEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilitydataXEditFieldLabel.Text = 'Probability(data<X)';
            app.DataLess = uieditfield(app.UIFigure, 'numeric');
            app.DataLess.Editable = 'off';
            app.ProbabilitydataXEditField_2Label = uilabel(app.UIFigure);
            app.ProbabilitydataXEditField_2Label.Text = 'Probability(data>X)';
            app.DataHigher = uieditfield(app.UIFigure, 'numeric');
            app.DataHigher.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = normal_distribution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end